classdef xyz2stl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ImportXYZDataPanel             matlab.ui.container.Panel
        SelectinputdataXYZpointsanddelimitertypeLabel  matlab.ui.control.Label
        SelectFileButton               matlab.ui.control.Button
        FieldDelimiterListBoxLabel     matlab.ui.control.Label
        FieldDelimiterListBox          matlab.ui.control.ListBox
        ofheaderrowsEditFieldLabel     matlab.ui.control.Label
        HeaderSizeEditField            matlab.ui.control.NumericEditField
        WhichcolumnscontainxyandzinformationLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        xdatacolumnEditFieldLabel      matlab.ui.control.Label
        xdatacolumnEditField           matlab.ui.control.NumericEditField
        ydatacolumnEditFieldLabel      matlab.ui.control.Label
        ydatacolumnEditField           matlab.ui.control.NumericEditField
        zdatacolumnEditFieldLabel      matlab.ui.control.Label
        zdatacolumnEditField           matlab.ui.control.NumericEditField
        SelectdatatoexcludeifanyLabel  matlab.ui.control.Label
        DropDown_no_DataValue_dimension  matlab.ui.control.DropDown
        DropDown_noDataValue_operator  matlab.ui.control.DropDown
        EditField_noDataValue          matlab.ui.control.NumericEditField
        TextArea_4                     matlab.ui.control.TextArea
        TextArea_5                     matlab.ui.control.TextArea
        TextArea_6                     matlab.ui.control.TextArea
        CreateSTLPanel                 matlab.ui.container.Panel
        InterpolatesurfacebilinearinterpexporttoSTLLabel  matlab.ui.control.Label
        ChooseSaveLocationButton       matlab.ui.control.Button
        CreateSTLButton                matlab.ui.control.Button
        ResolutionEditFieldLabel       matlab.ui.control.Label
        ResolutionEditField            matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel        matlab.ui.control.Label
        SmoothingEditField             matlab.ui.control.NumericEditField
        TextArea_2                     matlab.ui.control.TextArea
        TextArea_3                     matlab.ui.control.TextArea
        SmoothingFactorSlider          matlab.ui.control.Slider
        xyz2stlAtoolforconvertingpointdatainXYZformattoaLabel  matlab.ui.control.Label
        xyz2stlTextArea                matlab.ui.control.TextArea
    end


    properties (Access = private)
        XYZfile = ''; % Description
        STLfile = '';
        delimiter = 'Comma';
        x_column = 1;
        y_column = 2;
        z_column = 3;
        gridspace = 0;
        smoothfactor = 1;
        header_length = 0;
        noDataValue_dimension = '';
        noDataValue_operator = '=';
        noDataValue = NaN;
    end


    methods (Access = private)

        % Callback function
        function ConverttoSTLButtonPushed(app, event)
            % Ensure that an input file and an output file were selected
            filewarn = 0;
            if filewarn == 0
                if strcmp(app.XYZfile,'') == 1
                    msgbox('No input file selected. Select a file and try again.', 'Error', 'warn');
                    filewarn = filewarn + 1;
                end
                if strcmp(app.STLfile,'') == 1
                    msgbox('No STL output file selected. Select a destination and try again.', 'Error', 'warn');
                    filewarn = filewarn + 1;
                end
            end
            if filewarn ~= 0
                error('File path / name error.')
            end
            
            % Read in the x,y,z data
            if strcmp(app.delimiter,'Tab')
                m = dlmread(app.XYZfile,'	',app.header_length+1,0);
            end
            if strcmp(app.delimiter,'Comma')
                m = dlmread(app.XYZfile,',',app.header_length+1,0);
            end
            if strcmp(app.delimiter,'Space')
                m = dlmread(app.XYZfile,' ',app.header_length+1,0);
            end
            x = m(:,app.x_column);
            y = m(:,app.y_column);
            z = m(:,app.z_column);
            
            % Eliminate data beyond threshold values
            if app.noDataValue(~isnan(app.noDataValue))
                if strcmp(app.noDataValue_dimension,'X') == 1
                    if strcmp(app.noDataValue_operator,'=') == 1
                        [rows, ~] = find(x == app.noDataValue);
                    elseif strcmp(app.noDataValue_operator,'<') == 1
                        [rows, ~] = find(x < app.noDataValue);
                    elseif strcmp(app.noDataValue_operator,'>') == 1
                        [rows, ~] = find(x > app.noDataValue);
                    elseif strcmp(app.noDataValue_operator,'<=') == 1
                        [rows, ~] = find(x <= app.noDataValue);
                    elseif strcmp(app.noDataValue_operator,'>=') == 1
                        [rows, ~] = find(x >= app.noDataValue);
                    end
                elseif strcmp(app.noDataValue_dimension,'Y') == 1
                    [rows, ~] = find(y < app.noDataValue);
                    if strcmp(app.noDataValue_operator,'=') == 1
                        [rows, ~] = find(y == app.noDataValue);
                    elseif strcmp(app.noDataValue_operator,'<') == 1
                        [rows, ~] = find(y < app.noDataValue);
                    elseif strcmp(app.noDataValue_operator,'>') == 1
                        [rows, ~] = find(y > app.noDataValue);
                    elseif strcmp(app.noDataValue_operator,'<=') == 1
                        [rows, ~] = find(y <= app.noDataValue);
                    elseif strcmp(app.noDataValue_operator,'>=') == 1
                    end
                elseif strcmp(app.noDataValue_dimension,'Z') == 1
                    [rows, ~] = find(z < app.noDataValue);
                    if strcmp(app.noDataValue_operator,'=') == 1
                        [rows, ~] = find(z == app.noDataValue);
                    elseif strcmp(app.noDataValue_operator,'<') == 1
                        [rows, ~] = find(z < app.noDataValue);
                    elseif strcmp(app.noDataValue_operator,'>') == 1
                        [rows, ~] = find(z > app.noDataValue);
                    elseif strcmp(app.noDataValue_operator,'<=') == 1
                        [rows, ~] = find(z <= app.noDataValue);
                    elseif strcmp(app.noDataValue_operator,'>=') == 1
                    end
                end
            end
            if exist('rows', 'var')
                x(rows) = [];
                y(rows) = [];
                z(rows) = [];
            end
            
            % Create the XY grid
            if app.gridspace == 0
                app.gridspace = ( max(x)-min(x) ) / 100;
            end
            x_bounds = min(x):app.gridspace:max(x);
            y_bounds = min(y):app.gridspace:max(y);
            [X,Y] = meshgrid(x_bounds,y_bounds);
            Z = gridfit(x,y,z,x_bounds,y_bounds,'smoothness',app.smoothfactor,'interp','bilinear','extend','always');
            
            % Interpolate points to grid & exclude data outside of (x,y) domain
            k = boundary(x,y,1);
            in = inpolygon(X,Y,x(k),y(k));
            X(~in) = NaN;
            Y(~in) = NaN;
            [f,v,~] = surf2patch(X,Y,Z,'triangles'); % make triangular faces and vertices from gridded data
            
            % Delete vertices containing NaN values from the STL (inspired by Pau Micó's stlDelVerts function in the stlTools Add-On)
            vdel = find(any(isnan(v),2));
            vnew = v;
            tags = 1:length(v);
            vnew(vdel,:) = [];
            tags(vdel) = [];
            
            % Delete faces
            fnew = f;
            [fdel,~] = find(ismember(f,vdel)); % find the position (rows) of the faces to delete
            fnew(fdel,:) = [];
            
            % Rename faces, as some of the vertices have been deleted
            flist = reshape(fnew,numel(fnew),1);
            [~,ind] = ismember(flist,tags);
            fnew = reshape(ind,numel(flist)/3,3);
            
            % Write the x,y,z data to STL
            stlWrite(app.STLfile,fnew,vnew);
            
            % Announce successful creation of the STL
            msgbox('STL has been created.','Success');
        end

        % Callback function
        function FieldDelimiterValueChanged(app, event)
            app.delimiter = app.FieldDelimiterListBox.Value;
        end

        % Callback function
        function GridSpacingValueChanged(app, event)
            app.gridspace = app.ResolutionEditField.Value; 
        end

        % Callback function
        function HeaderRowNumValueChanged(app, event)
            app.header_length = app.HeaderSizeEditField.Value;
        end

        % Callback function
        function SavePromptButtonPushed(app, event)
            [stlFileName,stlPathName] = uiputfile('*.stl');
            app.STLfile = strcat(stlPathName,stlFileName);
        end

        % Callback function
        function SelectFileButtonPushed(app, event)
            [xyzfilename, xyzpathname] = uigetfile('*.*');
            app.XYZfile = strcat(xyzpathname,xyzfilename);
        end

        % Value changed function: SmoothingFactorSlider
        function SmoothingFactorSliderValueChanged(app, event)
            SmoothingSliderValue = app.SmoothingFactorSlider.Value;
            app.SmoothingEditField.Value = round(SmoothingSliderValue,0); % round to nearest integer
            app.smoothfactor = round(SmoothingSliderValue,0); % round to the nearest integer
        end

        % Callback function
        function SmoothingFactorValueChanged(app, event)
            app.SmoothingFactorSlider.Value = app.SmoothingEditField.Value;
            app.smoothfactor = app.SmoothingEditField.Value;
        end

        % Value changed function: DropDown_no_DataValue_dimension
        function noDataDimensionValueChanged(app, event)
            app.noDataValue_dimension = app.DropDown_no_DataValue_dimension.Value;
        end

        % Value changed function: DropDown_noDataValue_operator
        function noDataOperatorValueChanged(app, event)
            app.noDataValue_operator = app.DropDown_noDataValue_operator.Value;
        end

        % Value changed function: EditField_noDataValue
        function noDataValueChanged(app, event)
            app.noDataValue = app.EditField_noDataValue.Value;
        end

        % Callback function
        function xColValueChanged(app, event)
            app.x_column = app.xdatacolumnEditField.Value;
        end

        % Callback function
        function yColValueChanged(app, event)
            app.y_column = app.ydatacolumnEditField.Value;
        end

        % Callback function
        function zColValueChanged(app, event)
            app.z_column = app.zdatacolumnEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ImportXYZDataPanel = uipanel(app.UIFigure);
            app.ImportXYZDataPanel.Title = 'Import XYZ Data';
            app.SelectinputdataXYZpointsanddelimitertypeLabel = uilabel(app.ImportXYZDataPanel);
            app.SelectinputdataXYZpointsanddelimitertypeLabel.Text = 'Select input data (XYZ points) and delimiter type:';
            app.SelectFileButton = uibutton(app.ImportXYZDataPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.FieldDelimiterListBoxLabel = uilabel(app.ImportXYZDataPanel);
            app.FieldDelimiterListBoxLabel.Text = 'Field Delimiter';
            app.FieldDelimiterListBox = uilistbox(app.ImportXYZDataPanel);
            app.FieldDelimiterListBox.Items = {'Comma', 'Tab', 'Space'};
            app.FieldDelimiterListBox.ValueChangedFcn = createCallbackFcn(app, @FieldDelimiterValueChanged, true);
            app.FieldDelimiterListBox.Value = 'Comma';
            app.ofheaderrowsEditFieldLabel = uilabel(app.ImportXYZDataPanel);
            app.ofheaderrowsEditFieldLabel.Text = '# of header rows';
            app.HeaderSizeEditField = uieditfield(app.ImportXYZDataPanel, 'numeric');
            app.HeaderSizeEditField.ValueChangedFcn = createCallbackFcn(app, @HeaderRowNumValueChanged, true);
            app.HeaderSizeEditField.Limits = [0 Inf];
            app.HeaderSizeEditField.RoundFractionalValues = 'on';
            app.HeaderSizeEditField.ValueDisplayFormat = '%.0f';
            app.WhichcolumnscontainxyandzinformationLabel = uilabel(app.ImportXYZDataPanel);
            app.WhichcolumnscontainxyandzinformationLabel.Text = 'Which columns contain x, y, and z information?';
            app.TextArea = uitextarea(app.ImportXYZDataPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Enter integer values only,'; 'where column A = 1, column B = 2, etc.'};
            app.xdatacolumnEditFieldLabel = uilabel(app.ImportXYZDataPanel);
            app.xdatacolumnEditFieldLabel.Text = 'x data column #';
            app.xdatacolumnEditField = uieditfield(app.ImportXYZDataPanel, 'numeric');
            app.xdatacolumnEditField.ValueChangedFcn = createCallbackFcn(app, @xColValueChanged, true);
            app.xdatacolumnEditField.ValueDisplayFormat = '%.0f';
            app.xdatacolumnEditField.Value = 1;
            app.ydatacolumnEditFieldLabel = uilabel(app.ImportXYZDataPanel);
            app.ydatacolumnEditFieldLabel.Text = 'y data column #';
            app.ydatacolumnEditField = uieditfield(app.ImportXYZDataPanel, 'numeric');
            app.ydatacolumnEditField.ValueChangedFcn = createCallbackFcn(app, @yColValueChanged, true);
            app.ydatacolumnEditField.ValueDisplayFormat = '%.0f';
            app.ydatacolumnEditField.Value = 2;
            app.zdatacolumnEditFieldLabel = uilabel(app.ImportXYZDataPanel);
            app.zdatacolumnEditFieldLabel.Text = 'z data column #';
            app.zdatacolumnEditField = uieditfield(app.ImportXYZDataPanel, 'numeric');
            app.zdatacolumnEditField.ValueChangedFcn = createCallbackFcn(app, @zColValueChanged, true);
            app.zdatacolumnEditField.ValueDisplayFormat = '%.0f';
            app.zdatacolumnEditField.Value = 3;
            app.SelectdatatoexcludeifanyLabel = uilabel(app.ImportXYZDataPanel);
            app.SelectdatatoexcludeifanyLabel.Text = 'Select data to exclude, if any:';
            app.DropDown_no_DataValue_dimension = uidropdown(app.ImportXYZDataPanel);
            app.DropDown_no_DataValue_dimension.Items = {'(none)', 'X', 'Y', 'Z'};
            app.DropDown_no_DataValue_dimension.ValueChangedFcn = createCallbackFcn(app, @noDataDimensionValueChanged, true);
            app.DropDown_no_DataValue_dimension.Value = '(none)';
            app.DropDown_noDataValue_operator = uidropdown(app.ImportXYZDataPanel);
            app.DropDown_noDataValue_operator.Items = {'=', '<', '>', '<=', '>='};
            app.DropDown_noDataValue_operator.ValueChangedFcn = createCallbackFcn(app, @noDataOperatorValueChanged, true);
            app.DropDown_noDataValue_operator.Value = '=';
            app.EditField_noDataValue = uieditfield(app.ImportXYZDataPanel, 'numeric');
            app.EditField_noDataValue.ValueChangedFcn = createCallbackFcn(app, @noDataValueChanged, true);
            app.EditField_noDataValue.ValueDisplayFormat = '%11.8g';
            app.TextArea_4 = uitextarea(app.ImportXYZDataPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Dimension'};
            app.TextArea_5 = uitextarea(app.ImportXYZDataPanel);
            app.TextArea_5.Editable = 'off';
            app.TextArea_5.Value = {'Operator'};
            app.TextArea_6 = uitextarea(app.ImportXYZDataPanel);
            app.TextArea_6.Editable = 'off';
            app.TextArea_6.Value = {'Value'};
            app.CreateSTLPanel = uipanel(app.UIFigure);
            app.CreateSTLPanel.Title = 'Create STL';
            app.InterpolatesurfacebilinearinterpexporttoSTLLabel = uilabel(app.CreateSTLPanel);
            app.InterpolatesurfacebilinearinterpexporttoSTLLabel.Text = 'Interpolate surface (bilinear interp.) & export to STL';
            app.ChooseSaveLocationButton = uibutton(app.CreateSTLPanel, 'push');
            app.ChooseSaveLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SavePromptButtonPushed, true);
            app.ChooseSaveLocationButton.Text = 'Choose Save Location';
            app.CreateSTLButton = uibutton(app.CreateSTLPanel, 'push');
            app.CreateSTLButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoSTLButtonPushed, true);
            app.CreateSTLButton.Text = 'Create STL';
            app.ResolutionEditFieldLabel = uilabel(app.CreateSTLPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.CreateSTLPanel, 'numeric');
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @GridSpacingValueChanged, true);
            app.SmoothingEditFieldLabel = uilabel(app.CreateSTLPanel);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.CreateSTLPanel, 'numeric');
            app.SmoothingEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingFactorValueChanged, true);
            app.SmoothingEditField.Limits = [1e-08 Inf];
            app.SmoothingEditField.ValueDisplayFormat = '%.1e';
            app.SmoothingEditField.Value = 1e-08;
            app.TextArea_2 = uitextarea(app.CreateSTLPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Set to 0 to auto-calculate'};
            app.TextArea_3 = uitextarea(app.CreateSTLPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Excess smoothing will distort the data. 1.0e-08 is effectively "no smoothing."'};
            app.SmoothingFactorSlider = uislider(app.CreateSTLPanel);
            app.SmoothingFactorSlider.Limits = [1 100];
            app.SmoothingFactorSlider.MajorTickLabels = {'1', '', '', '', '', '', '', '', '', '100'};
            app.SmoothingFactorSlider.ValueChangedFcn = createCallbackFcn(app, @SmoothingFactorSliderValueChanged, true);
            app.SmoothingFactorSlider.Value = 1;
            app.xyz2stlAtoolforconvertingpointdatainXYZformattoaLabel = uilabel(app.UIFigure);
            app.xyz2stlAtoolforconvertingpointdatainXYZformattoaLabel.Text = 'xyz2stl';
            app.xyz2stlTextArea = uitextarea(app.UIFigure);
            app.xyz2stlTextArea.Value = {'This tool converts x,y,z point data stored in a text or CSV file to STL (STereo Lithography) format, a common file type used in design and engineering applications. Last revised September 2017 by Nick Richmond (nicholas.richmond@maine.edu).'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = xyz2stl

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end